classdef ARES_Micro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MainTabs                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        GridLayout3                     matlab.ui.container.GridLayout
        DisconnectVNA                   matlab.ui.control.Button
        ConnectVNA                      matlab.ui.control.Button
        SoftDisconnectButton            matlab.ui.control.Button
        ConnectionStatusActual          matlab.ui.control.Label
        ConnectionStatusLabel           matlab.ui.control.Label
        DisconnectPicoButton            matlab.ui.control.Button
        ConnecttoPicoButton             matlab.ui.control.Button
        VNALabel                        matlab.ui.control.Label
        VNAStatusLabel                  matlab.ui.control.Label
        VNASliderDropDown               matlab.ui.control.DropDown
        TuneTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        LiveViewButton                  matlab.ui.control.StateButton
        EditGap_8                       matlab.ui.control.NumericEditField
        EditGap_7                       matlab.ui.control.NumericEditField
        EditGap_6                       matlab.ui.control.NumericEditField
        EditGap_5                       matlab.ui.control.NumericEditField
        EditGap_4                       matlab.ui.control.NumericEditField
        EditGap_3                       matlab.ui.control.NumericEditField
        EditGap_2                       matlab.ui.control.NumericEditField
        EditGap                         matlab.ui.control.NumericEditField
        RecallValueButton               matlab.ui.control.Button
        CalibrateButton                 matlab.ui.control.Button
        Slider_8                        matlab.ui.control.Slider
        Slider_7                        matlab.ui.control.Slider
        Slider_6                        matlab.ui.control.Slider
        Slider_5                        matlab.ui.control.Slider
        Slider_4                        matlab.ui.control.Slider
        Slider_3                        matlab.ui.control.Slider
        Slider_2                        matlab.ui.control.Slider
        Slider                          matlab.ui.control.Slider
        NumberofActuatorsEditField      matlab.ui.control.NumericEditField
        NumberofActuatorsEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LogsTab                         matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        LogDataTextArea                 matlab.ui.control.TextArea
        LogDataLabel                    matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        GetLogButton                    matlab.ui.control.Button
        LogGapsButton                   matlab.ui.control.Button
        OptimizeTab                     matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout12_2                  matlab.ui.container.GridLayout
        FreqMaxEditField                matlab.ui.control.NumericEditField
        FreqMaxEditFieldLabel           matlab.ui.control.Label
        FreqMinEditField                matlab.ui.control.NumericEditField
        FreqMinEditFieldLabel           matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        StartingStepSizeEditField       matlab.ui.control.NumericEditField
        StartingStepSizeEditFieldLabel  matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        GridLayout16                    matlab.ui.container.GridLayout
        LiveCheckBox                    matlab.ui.control.CheckBox
        AlgorithmicGradDescentButton    matlab.ui.control.Button
        BayesianOptimizationButton_2    matlab.ui.control.Button
        AlgorithmicPeakInsertionBasicButton  matlab.ui.control.Button
        TimeElapsedsLabel               matlab.ui.control.Label
        OpTimer                         matlab.ui.control.Label
        AlgorithmicPeakInsertionButton  matlab.ui.control.Button
        SmithChartTab                   matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        GetSmithChartButton             matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
    end

    %  -----------------------------------------------------------------------
    %  ARES_Micro.mlapp
    % 
    %  Application to Control Pico 2
    % 
    %  Author: Peter Skrdla nad Joe Kritenbrink @ ARES Lab
    %  Purdue Univeristy (2026)
    % 
    %  The following assumptions are made:
    %     The raspberry pi is connected via serial USB
    % -----------------------------------------------------------------------

    
    properties (Access = private)
        slider; %Turns slider object on/off depending on status of pico connection
        editGaps; %Turns input object on/off depending on status of pico connection
        getGaps; %The array of gap values currently in use
        LiveView = [];   % VNALiveView, [] until live plotting starts
        iteratePrecision = 1; %Determines the number of iterations over the algorithms freq range
        startingIndexPrecision = 1; %Sets the step size of each iteration
        useSurrogateModel = true;
    end
    
    properties (Access = public)
        pico; % Microcontroller object
        PROMPT = "EXECUTE:"; % Prompt sent from Pico for new input
        getData = "logged_Data.txt";
        picoIsSetup = false; %Pico Setup Status
        isCal = false; %Pico calibration status
        fmin;
        fmax;

        % Cmap: Command Map
        % 
        % C00: EXIT                
        % C01: Calibrate         
        % C02: Recall Last Log   
        % C03: Specify gaps
        % C04: Specify one gap   
        % C05: Get the Pico Log  
        % C06:
        % 
        % For C03, Enter: C03 gap_val gap_val gap_val gap_val
        % For C04, Enter: C04 actuator # gap_val
        COMMANDS = ["C00", "C01", "C02", "C03", "C04", "C05"]; %Available inputs
        VNA = [];        % VNAInstCtrl driver, [] when disconnected
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add paths for application support functions.
            appDir = fileparts(mfilename('fullpath'));
            addpath(genpath(fullfile(appDir, 'support')));
            app.fmin = app.FreqMinEditField.Value * 1e9;
            app.fmax = app.FreqMaxEditField.Value * 1e9;

            try
                app.VNASliderDropDown.Items = vnaAddressList();
            catch ME
                app.VNAStatusLabel.Text = 'Status: no instrument CSV';
            end

            app.slider = gobjects(1, 8); 
            app.editGaps = gobjects(1, 8);
    
            % Safely populate, disable, and route callbacks
            for i = 1:8
                if i == 1
                    slidName = 'Slider';
                    entName = 'EditGap';
                    
                else
                    slidName = sprintf('Slider_%d', i);
                    entName = sprintf('EditGap_%d', i);
                end
                
                app.slider(i) = app.(slidName);
                app.slider(i).Enable = 'off';
                app.slider(i).ValueChangedFcn = @(src, event) app.SliderValueChanged(event);
                
                app.editGaps(i) = app.(entName);
                app.editGaps(i).Enable = 'off';
                app.editGaps(i).ValueChangedFcn = @(src, event) app.EditGapValueChanged(event);
            end

            % app.StartingStepSizeEditField.Enable = 'off';
            % app.NumberofIterationsEditField.Enable = 'off';
            % app.AlgorithmicPeakInsertionButton.Enable = 'off';
        end

        % Button pushed function: ConnecttoPicoButton
        function ConnecttoPicoButtonPushed(app, event)
            try
                % Communication Setup
                PORT = "COM3";
                BAUD = 15000;
                
                if ~isempty(serialportfind)
                    delete(serialportfind);
                end
        
                app.pico = serialport(PORT, BAUD, "Tag", "Pico");
                configureTerminator(app.pico, "CR/LF");
                flush(app.pico);
                
                %Re-execute the main.py on the pico
                if app.picoIsSetup
                    easyWakeUp(app);
        
                else
                    hardWakeUp(app); %Must execute every time new code is uploaded to pico or you disconnect from it
                    easyWakeUp(app);
                    app.picoIsSetup = true;
                end
            
                %Clear any previous commands from the Pico
                flush(app.pico)
                app.ConnectionStatusActual.Text = 'Connected';

                waitForResponse(app);
                app.ConnectionStatusActual.Text = 'Ready';

            catch exception
                app.ConnectionStatusActual.Text = 'Connection Failed';
                uialert(app.UIFigure, exception.message, 'Error Occurred');
            end

        end

        % Button pushed function: DisconnectPicoButton
        function DisconnectPicoButtonPushed(app, event)
            if app.picoIsSetup
                waitForResponse(app);
                writeline(app.pico, app.COMMANDS(1));
                app.ConnectionStatusActual.Text = 'Disconnected';
                delete(app.pico);
                clearvars;
                app.picoIsSetup = false;
            end
        end

        % Value changed function: NumberofActuatorsEditField
        function NumberofActuatorsEditFieldValueChanged(app, event)
  
            %Update Gaps
            value = app.NumberofActuatorsEditField.Value;
            app.getGaps = zeros(value, 1);
            
            % Loop through ALL 8 sliders 
                for i = 1:numel(app.slider)
                    if i <= value
                        app.slider(i).Enable = 'on';  % Enable the ones requested
                        app.editGaps(i).Enable = 'on';
                    else
                        app.slider(i).Enable = 'off'; % Grey out the rest
                        app.editGaps(i).Enable = 'off';
                    end
                end

        end

        % Value changed function: Slider, Slider_2, Slider_3, Slider_4, 
        % ...and 4 other components
        function SliderValueChanged(app, event)
            if app.picoIsSetup
                waitForResponse(app);
                
                srcSlider = event.Source;
                localIdx = find(app.slider == srcSlider, 1);
                value = floor(srcSlider.Value);
                
                if ~isempty(localIdx) && localIdx <= numel(app.getGaps)
                    app.getGaps(localIdx) = value;
                    
                    % Cross-link UI to paired Edit Box
                    app.editGaps(localIdx).Value = value;
           
                    % Update Actuator via Serial using the safe local index
                    cmdToSend = sprintf("%s %d %d", app.COMMANDS(5), localIdx, value);
                    writeline(app.pico, cmdToSend);
                end
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            if app.picoIsSetup
                waitForResponse(app);
                writeline(app.pico, app.COMMANDS(2));
                app.isCal = true;
            end
        end

        % Button pushed function: SoftDisconnectButton
        function SoftDisconnectButtonPushed(app, event)
            %Ends the current running program
            if app.picoIsSetup
                waitForResponse(app);
                writeline(app.pico, app.COMMANDS(1));
                app.ConnectionStatusActual.Text = 'Soft Disconnect';
            end
        end

        % Button pushed function: LogGapsButton
        function LogGapsButtonPushed(app, event)
            if app.picoIsSetup
                waitForResponse(app);
                if (~setSingleGap)
                    cmdToSend = "log" + string(app.COMMANDS(3)) + " " + join(string(app.getGaps));
                else
                    cmdToSend = "log" + string(app.COMMANDS(4)) + " " + setSingleGap + " " + app.getGaps;
                end

                writeline(app.pico, cmdToSend);
            end
        end

        % Button pushed function: RecallValueButton
        function RecallValueButtonPushed(app, event)
            cmdToSend = app.COMMANDS(3);
            writeline(app.pico, cmdToSend);
        end

        % Value changed function: EditGap, EditGap_2, EditGap_3, 
        % ...and 5 other components
        function EditGapValueChanged(app, event)
           if app.picoIsSetup
                waitForResponse(app);
                
                srcEnt = event.Source;
                localIdx = find(app.editGaps == srcEnt, 1);
                value = floor(srcEnt.Value);
                
                if ~isempty(localIdx) && localIdx <= numel(app.getGaps)
                    app.getGaps(localIdx) = value;
                    
                    % Cross-link UI to paired Slider
                    app.slider(localIdx).Value = value;

                    % Update Actuator via Serial using the safe local index
                    cmdToSend = sprintf("%s %d %d", app.COMMANDS(5), localIdx, value);
                    writeline(app.pico, cmdToSend);
                end
            end
        end

        % Button pushed function: GetLogButton
        function GetLogButtonPushed(app, event)
            if app.picoIsSetup
                % Clear old data out of the text area and show status
                app.LogDataTextArea.Value = "Requesting log file from Pico flash...";
                drawnow; 
                
                % Wait for standard REPL/program prompt readiness
                waitForResponse(app);
                
                % Send C05 down the pipe (Assumes app.COMMANDS(6) is "C05")
                CMD_EXTRACT_LOG = app.COMMANDS(6); 
                writeline(app.pico, CMD_EXTRACT_LOG);
                
                % Swap the handshake token to catch the file transfer header
                oldPrompt = app.PROMPT;
                app.PROMPT = "---START_FILE_TRANSFER---";
                waitForResponse(app);
                
                % Reset prompt back to standard execution tracking
                app.PROMPT = oldPrompt;
                
                % FIX: Use a vertical string vector to prevent dimension mismatch errors
                logLines = "--- Log Retrieved: " + string(datetime('now')) + " ---" ;
                
                % Stream data lines dynamically until stop token is reached
                while true
                    if app.pico.NumBytesAvailable > 0
                        line = string(readline(app.pico));
            
                        % Stop token detection
                        if contains(line, "---END_FILE_TRANSFER---")
                            logLines = [logLines; "--- File Transfer Complete! ---"]; % Append vertically
                            break;
                        end
            
                        % Strip carriage returns and append clean string vertically
                        cleanLine = strtrim(line);
                        logLines = [logLines; cleanLine]; %#ok<AGROW>
                        
                        % Update screen live so the reader sees the file stream
                        app.LogDataTextArea.Value = logLines;
                        drawnow;
                    else
                        pause(0.005); 
                    end
                end
                
                % Final complete UI text paint
                app.LogDataTextArea.Value = logLines;
            end
        end

        % Button pushed function: ConnectVNA
        function VNAButtonPushed(app, event)
            try
                app.VNAStatusLabel.Text = 'Status: Connecting...'; drawnow;
                app.VNA = connectVNA(app.VNASliderDropDown.Value);
                idParts = split(app.VNA.IDN, ",");
                app.VNAStatusLabel.Text = "Status: " + strjoin(idParts(1:min(2,end)), " ");
            catch ME
                app.VNAStatusLabel.Text = 'Status: Failed';
                uialert(app.UIFigure, ME.message, 'VNA Connection Error');
            end
        end

        % Value changed function: LiveViewButton
        function LiveViewPressed(app, event)
            try
                if event.Value                        % toggled ON
                    if isempty(app.LiveView)
                        app.LiveView = VNALiveView(app.VNA, app.UIAxes, 'Period', 0);
                    end
                    app.LiveView.start();
                else                                  % toggled OFF
                    app.LiveView.stop();
                end
            catch ME
                app.LiveViewButton.Value = false;
                uialert(app.UIFigure, ME.message, 'VNA Live View Error');
            end
        end

        % Button pushed function: DisconnectVNA
        function DisconnectVNAButton(app, event)
            disconnectVNA(app.VNA, app.LiveView);
            app.VNA = [];
            app.LiveView = [];
            app.VNAStatusLabel.Text = 'Status: Disconnected';
            if isprop(app, 'LiveViewButton'), app.LiveViewButton.Value = false; end
        end

        % Button pushed function: AlgorithmicPeakInsertionButton
        function AlgorithmicPeakInsertionButtonPushed(app, event)
            if app.picoIsSetup && ~isempty(app.VNA)
                %app.AlgorithmicPeakInsertionButton.Enable = 'on';
                
                if ~app.isCal
                    waitForResponse(app);
                    writeline(app.pico, app.COMMANDS(2));
                    app.isCal = true;
                else
                    waitForResponse(app); % Only call once if already calibrated
                end
                
                % Set Constants
                num_actuators = 4;
                gap_max = findMaxGap();
                peak_db = -inf(1, num_actuators); %Note: Gaps 1 and 2 correlate to S21 and gaps 3 and 4 correlate to S31;
                cutoffLo = app.fmin;
                cutoffHi = app.fmax;
                % Run the optimization loop
                tic;
                for i = 1:app.iteratePrecision
        
                    % Run the optimization pass
                    [currGaps, currDb, currFreq, cutoffLo, cutoffHi] = AlgoOpt(app, cutoffLo, cutoffHi, gap_max, num_actuators, app.startingIndexPrecision);
                    
                    % Check for improvement and update parameters
                    if mean(currDb) > mean(peak_db)
                        peak_db = currDb; 
                        app.getGaps = currGaps;
                    end
                    
                    % Coarse-to-Fine Step Reduction (Mutating the local copy ensures repeatable runs)
                    if app.startingIndexPrecision >= 2
                        app.startingIndexPrecision = max(1, floor(app.startingIndexPrecision / 2));
                    else
                        break; % Stop searching if we are already at maximum resolution
                    end
                end
                app.OpTimer.Text = num2str(toc);
                
                % Only attempt to update UI if gaps were successfully retrieved
                if ~isempty(app.getGaps)
                    app.NumberofActuatorsEditField.Value = length(app.getGaps);
                    disp(app.getGaps)
                    
                    % UPDATE ALL UI ELEMENTS WITH THE NEW OPTIMIZED VALUES
                    % (Assumes app.slider and app.editGaps arrays are constructed in startupFcn)
                    for idx = 1:numel(app.getGaps)
                        optimizedValue = app.getGaps(idx);
                        
                        % Update the Sliders array
                        app.slider(idx).Value = optimizedValue;
                        
                        % Update the tracking Edit Boxes array 
                        app.editGaps(idx).Value = optimizedValue;
                    end
                end
                
            else
                app.ConnectionStatusActual.Text = 'Connection Failed';
                uialert(app.UIFigure, 'Hardware or VNA connection is missing.', 'Error Occurred');
            end
        end

        % Value changed function: NumberofIterationsEditField
        function NumberofIterationsEditFieldValueChanged(app, event)
            
            if app.picoIsSetup && ~isempty(app.VNA)
                app.iteratePrecision = app.NumberofIterationsEditField.Value; 
            end
        end

        % Value changed function: StartingStepSizeEditField
        function StartingStepSizeEditFieldValueChanged(app, event)
            if app.picoIsSetup && ~isempty(app.VNA)
                app.startingIndexPrecision = app.StartingStepSizeEditField.Value;
            end
        end

        % Button pushed function: AlgorithmicPeakInsertionBasicButton
        function AlgorithmicPeakInsertionBasicButtonPushed(app, event)
            if app.picoIsSetup && ~isempty(app.VNA)
                %app.AlgorithmicPeakInsertionButton.Enable = 'on';
                
                if ~app.isCal
                    waitForResponse(app);
                    writeline(app.pico, app.COMMANDS(2));
                    app.isCal = true;
                else
                    waitForResponse(app); % Only call once if already calibrated
                end

                % Set Constants
                num_actuators = 4;
                gap_max = findMaxGap();
                peak_db = -inf(1, num_actuators); %Note: Gaps 1 and 2 correlate to S21 and gaps 3 and 4 correlate to S31;
                centerfreq =  1.5e9;

                % Run the optimization loop
                tic;
                for i = 1:app.iteratePrecision
        
                    % Run the optimization pass
                    [currGaps, currDb, currFreq, cutoffLo, cutoffHi] = AlgoOptBasic(app, app.fmin, app.fmax, gap_max, num_actuators, app.startingIndexPrecision, centerfreq);
                    
                    % Check for improvement and update parameters
                    if mean(currDb) > mean(peak_db)
                        peak_db = currDb; 
                        app.getGaps = currGaps;
                    end
                    
                    % Coarse-to-Fine Step Reduction (Mutating the local copy ensures repeatable runs)
                    if app.startingIndexPrecision >= 2
                        app.startingIndexPrecision = max(1, floor(app.startingIndexPrecision / 2));
                    else
                        break; % Stop searching if we are already at maximum resolution
                    end
                end
                app.OpTimer.Text = num2str(toc);
                
                % Only attempt to update UI if gaps were successfully retrieved
                if ~isempty(app.getGaps)
                    app.NumberofActuatorsEditField.Value = length(app.getGaps);
                    disp(app.getGaps)
                    
                    % UPDATE ALL UI ELEMENTS WITH THE NEW OPTIMIZED VALUES
                    % (Assumes app.slider and app.editGaps arrays are constructed in startupFcn)
                    for idx = 1:numel(app.getGaps)
                        optimizedValue = app.getGaps(idx);
                        
                        % Update the Sliders array
                        app.slider(idx).Value = optimizedValue;
                        
                        % Update the tracking Edit Boxes array 
                        app.editGaps(idx).Value = optimizedValue;
                    end
                end
            else
                app.ConnectionStatusActual.Text = 'Connection Failed';
                uialert(app.UIFigure, 'Hardware or VNA connection is missing.', 'Error Occurred');
            end
        end

        % Button pushed function: AlgorithmicGradDescentButton
        function AlgorithmicGradDescentButtonPushed(app, event)
            if app.picoIsSetup && ~isempty(app.VNA)
                %app.AlgorithmicPeakInsertionButton.Enable = 'on';

                if ~app.isCal
                    waitForResponse(app);
                    writeline(app.pico, app.COMMANDS(2));
                    app.isCal = true;
                else
                    waitForResponse(app); % Only call once if already calibrated
                end
                variableEta = false;
                tic; %Start time
                % Obj 1 & 2: Min insertion loss allowed (dB)
                % Obj 3: Max drift from 1.5 GHz (Hz)
                % Obj 4: Threshold for return loss (dB)
                % Obj 5: Max dB difference between paths
                % Obj 6: Exact phase difference (degrees)
                weights = [0.2, 0.2,  0.4, 0, 0.2, 0]; %arbitrary 
                
                app.getGaps = evaGradAlgo(app, app.fmin, app.fmax, weights, app.useSurrogateModel, variableEta);

                app.OpTimer.Text = num2str(toc);
            end
        end

        % Value changed function: FreqMinEditField
        function FreqMinEditFieldValueChanged(app, event)
            %app.fmin = 1.3e9;
            %app.fmax = 1.8e9;
            app.fmin = app.FreqMinEditField.Value * 1e9;
            
        end

        % Value changed function: FreqMaxEditField
        function FreqMaxEditFieldValueChanged(app, event)
            app.fmax = app.FreqMaxEditField.Value * 1e9;
        end

        % Value changed function: LiveCheckBox
        function LiveCheckBoxValueChanged(app, event)
            app.useSurrogateModel = app.LiveCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MainTabs = uitabgroup(app.GridLayout);
            app.SetupTab = uitab(app.MainTabs);
            app.SetupTab.Title = 'Setup';
            app.GridLayout2 = uigridlayout(app.SetupTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.VNASliderDropDown = uidropdown(app.GridLayout3);
            app.VNASliderDropDown.Items = {};
            app.VNASliderDropDown.Placeholder = 'Enter address:';
            app.VNASliderDropDown.Value = {};
            app.VNAStatusLabel = uilabel(app.GridLayout3);
            app.VNAStatusLabel.Text = 'Status:';
            app.VNALabel = uilabel(app.GridLayout3);
            app.VNALabel.Text = 'VNA';
            app.ConnecttoPicoButton = uibutton(app.GridLayout3, 'push');
            app.ConnecttoPicoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoPicoButtonPushed, true);
            app.ConnecttoPicoButton.Text = 'Connect to Pico';
            app.DisconnectPicoButton = uibutton(app.GridLayout3, 'push');
            app.DisconnectPicoButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectPicoButtonPushed, true);
            app.DisconnectPicoButton.Text = 'Disconnect Pico';
            app.ConnectionStatusLabel = uilabel(app.GridLayout3);
            app.ConnectionStatusLabel.Text = 'Status:';
            app.ConnectionStatusActual = uilabel(app.GridLayout3);
            app.ConnectionStatusActual.Text = '';
            app.SoftDisconnectButton = uibutton(app.GridLayout3, 'push');
            app.SoftDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @SoftDisconnectButtonPushed, true);
            app.SoftDisconnectButton.Text = 'Soft Disconnect';
            app.ConnectVNA = uibutton(app.GridLayout3, 'push');
            app.ConnectVNA.ButtonPushedFcn = createCallbackFcn(app, @VNAButtonPushed, true);
            app.ConnectVNA.Text = 'Connect VNA';
            app.DisconnectVNA = uibutton(app.GridLayout3, 'push');
            app.DisconnectVNA.ButtonPushedFcn = createCallbackFcn(app, @DisconnectVNAButton, true);
            app.DisconnectVNA.Text = 'Disconnect VNA';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'support', 'images', 'ARES Icon.png');
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.Image2 = uiimage(app.GridLayout10);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'support', 'images', 'logo-dark.svg');
            app.Image3 = uiimage(app.GridLayout10);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'support', 'images', 'github-mark.svg');
            app.TuneTab = uitab(app.MainTabs);
            app.TuneTab.Title = 'Tune';
            app.GridLayout4 = uigridlayout(app.TuneTab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'S-Parameters')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.NumberofActuatorsEditFieldLabel = uilabel(app.GridLayout5);
            app.NumberofActuatorsEditFieldLabel.Text = 'Number of Actuators';
            app.NumberofActuatorsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NumberofActuatorsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofActuatorsEditFieldValueChanged, true);
            app.Slider = uislider(app.GridLayout5);
            app.Slider.Limits = [1 400];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.Slider_2 = uislider(app.GridLayout5);
            app.Slider_2.Limits = [1 400];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2.Value = 1;
            app.Slider_3 = uislider(app.GridLayout5);
            app.Slider_3.Limits = [1 400];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_3.Value = 1;
            app.Slider_4 = uislider(app.GridLayout5);
            app.Slider_4.Limits = [1 400];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_4.Value = 1;
            app.Slider_5 = uislider(app.GridLayout5);
            app.Slider_5.Limits = [1 400];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_5.Value = 1;
            app.Slider_6 = uislider(app.GridLayout5);
            app.Slider_6.Limits = [1 400];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_6.Value = 1;
            app.Slider_7 = uislider(app.GridLayout5);
            app.Slider_7.Limits = [1 400];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_7.Value = 1;
            app.Slider_8 = uislider(app.GridLayout5);
            app.Slider_8.Limits = [1 400];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_8.Value = 1;
            app.CalibrateButton = uibutton(app.GridLayout5, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.RecallValueButton = uibutton(app.GridLayout5, 'push');
            app.RecallValueButton.ButtonPushedFcn = createCallbackFcn(app, @RecallValueButtonPushed, true);
            app.RecallValueButton.Text = 'Recall Value';
            app.EditGap = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap.Limits = [1 4000];
            app.EditGap.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap.Value = 1;
            app.EditGap_2 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_2.Limits = [1 4000];
            app.EditGap_2.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_2.Value = 1;
            app.EditGap_3 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_3.Limits = [1 4000];
            app.EditGap_3.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_3.Value = 1;
            app.EditGap_4 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_4.Limits = [1 4000];
            app.EditGap_4.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_4.Value = 1;
            app.EditGap_5 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_5.Limits = [1 4000];
            app.EditGap_5.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_5.Value = 1;
            app.EditGap_6 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_6.Limits = [1 4000];
            app.EditGap_6.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_6.Value = 1;
            app.EditGap_7 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_7.Limits = [1 4000];
            app.EditGap_7.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_7.Value = 1;
            app.EditGap_8 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_8.Limits = [1 4000];
            app.EditGap_8.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_8.Value = 1;
            app.LiveViewButton = uibutton(app.GridLayout5, 'state');
            app.LiveViewButton.ValueChangedFcn = createCallbackFcn(app, @LiveViewPressed, true);
            app.LiveViewButton.Text = 'Live View';
            app.LogsTab = uitab(app.MainTabs);
            app.LogsTab.Title = 'Logs';
            app.GridLayout6 = uigridlayout(app.LogsTab);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.LogGapsButton = uibutton(app.GridLayout7, 'push');
            app.LogGapsButton.ButtonPushedFcn = createCallbackFcn(app, @LogGapsButtonPushed, true);
            app.LogGapsButton.Text = 'Log Gaps';
            app.GetLogButton = uibutton(app.GridLayout7, 'push');
            app.GetLogButton.ButtonPushedFcn = createCallbackFcn(app, @GetLogButtonPushed, true);
            app.GetLogButton.Text = 'Get Log';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.LogDataLabel = uilabel(app.GridLayout8);
            app.LogDataLabel.Text = 'Log Data';
            app.LogDataTextArea = uitextarea(app.GridLayout8);
            app.OptimizeTab = uitab(app.MainTabs);
            app.OptimizeTab.Title = 'Optimize';
            app.GridLayout11 = uigridlayout(app.OptimizeTab);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.AlgorithmicPeakInsertionButton = uibutton(app.GridLayout12, 'push');
            app.AlgorithmicPeakInsertionButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmicPeakInsertionButtonPushed, true);
            app.AlgorithmicPeakInsertionButton.Text = 'Algorithmic: Peak Insertion';
            app.OpTimer = uilabel(app.GridLayout12);
            app.OpTimer.Text = '0';
            app.TimeElapsedsLabel = uilabel(app.GridLayout12);
            app.TimeElapsedsLabel.Text = 'Time Elapsed (s)';
            app.AlgorithmicPeakInsertionBasicButton = uibutton(app.GridLayout12, 'push');
            app.AlgorithmicPeakInsertionBasicButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmicPeakInsertionBasicButtonPushed, true);
            app.AlgorithmicPeakInsertionBasicButton.Text = 'Algorithmic: Peak Insertion Basic';
            app.BayesianOptimizationButton_2 = uibutton(app.GridLayout12, 'push');
            app.BayesianOptimizationButton_2.Text = 'Bayesian Optimization';
            app.GridLayout16 = uigridlayout(app.GridLayout12);
            app.AlgorithmicGradDescentButton = uibutton(app.GridLayout16, 'push');
            app.AlgorithmicGradDescentButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmicGradDescentButtonPushed, true);
            app.AlgorithmicGradDescentButton.Text = 'Algorithmic: Grad Descent';
            app.LiveCheckBox = uicheckbox(app.GridLayout16);
            app.LiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveCheckBoxValueChanged, true);
            app.LiveCheckBox.Text = 'Live';
            app.GridLayout12_2 = uigridlayout(app.GridLayout11);
            app.StartingStepSizeEditFieldLabel = uilabel(app.GridLayout12_2);
            app.StartingStepSizeEditFieldLabel.Text = 'Starting Step Size';
            app.StartingStepSizeEditField = uieditfield(app.GridLayout12_2, 'numeric');
            app.StartingStepSizeEditField.Limits = [1 Inf];
            app.StartingStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StartingStepSizeEditFieldValueChanged, true);
            app.StartingStepSizeEditField.Value = 1;
            app.NumberofIterationsEditFieldLabel = uilabel(app.GridLayout12_2);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.GridLayout12_2, 'numeric');
            app.NumberofIterationsEditField.Limits = [1 10];
            app.NumberofIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofIterationsEditFieldValueChanged, true);
            app.NumberofIterationsEditField.Value = 1;
            app.FreqMinEditFieldLabel = uilabel(app.GridLayout12_2);
            app.FreqMinEditFieldLabel.Text = 'Freq Min';
            app.FreqMinEditField = uieditfield(app.GridLayout12_2, 'numeric');
            app.FreqMinEditField.Limits = [0.1 20];
            app.FreqMinEditField.ValueChangedFcn = createCallbackFcn(app, @FreqMinEditFieldValueChanged, true);
            app.FreqMinEditField.Value = 0.1;
            app.FreqMaxEditFieldLabel = uilabel(app.GridLayout12_2);
            app.FreqMaxEditFieldLabel.Text = 'Freq Max';
            app.FreqMaxEditField = uieditfield(app.GridLayout12_2, 'numeric');
            app.FreqMaxEditField.Limits = [0.1 20];
            app.FreqMaxEditField.ValueChangedFcn = createCallbackFcn(app, @FreqMaxEditFieldValueChanged, true);
            app.FreqMaxEditField.Value = 20;
            app.SmithChartTab = uitab(app.MainTabs);
            app.SmithChartTab.Title = 'Smith Chart';
            app.GridLayout14 = uigridlayout(app.SmithChartTab);
            app.UIAxes4 = uiaxes(app.GridLayout14);
            title(app.UIAxes4, 'Smith Chart')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.GetSmithChartButton = uibutton(app.GridLayout15, 'push');
            app.GetSmithChartButton.Text = 'Get Smith Chart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARES_Micro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end