classdef ARES_Micro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MainTabs                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        GridLayout3                     matlab.ui.container.GridLayout
        ConnectVNA                      matlab.ui.control.Button
        VNASliderDropDown               matlab.ui.control.DropDown
        DisconnectVNA                   matlab.ui.control.Button
        SoftDisconnectButton            matlab.ui.control.Button
        ConnectionStatusActual          matlab.ui.control.Label
        ConnectionStatusLabel           matlab.ui.control.Label
        DisconnectPicoButton            matlab.ui.control.Button
        ConnecttoPicoButton             matlab.ui.control.Button
        VNALabel                        matlab.ui.control.Label
        VNAStatusLabel                  matlab.ui.control.Label
        TuneTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        LiveViewButton                  matlab.ui.control.StateButton
        EditGap_8                       matlab.ui.control.NumericEditField
        EditGap_7                       matlab.ui.control.NumericEditField
        EditGap_6                       matlab.ui.control.NumericEditField
        EditGap_5                       matlab.ui.control.NumericEditField
        EditGap_4                       matlab.ui.control.NumericEditField
        EditGap_3                       matlab.ui.control.NumericEditField
        EditGap_2                       matlab.ui.control.NumericEditField
        EditGap                         matlab.ui.control.NumericEditField
        RecallValueButton               matlab.ui.control.Button
        CalibrateButton                 matlab.ui.control.Button
        Slider_8                        matlab.ui.control.Slider
        Slider_7                        matlab.ui.control.Slider
        Slider_6                        matlab.ui.control.Slider
        Slider_5                        matlab.ui.control.Slider
        Slider_4                        matlab.ui.control.Slider
        Slider_3                        matlab.ui.control.Slider
        Slider_2                        matlab.ui.control.Slider
        Slider                          matlab.ui.control.Slider
        NumberofActuatorsEditField      matlab.ui.control.NumericEditField
        NumberofActuatorsEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LogsTab                         matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        LogDataTextArea                 matlab.ui.control.TextArea
        LogDataLabel                    matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        GetLogButton                    matlab.ui.control.Button
        LogGapsButton                   matlab.ui.control.Button
        OptimizeTab                     matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        GridLayout19                    matlab.ui.container.GridLayout
        WeightLabel                     matlab.ui.control.Label
        ObjectiveLabel                  matlab.ui.control.Label
        TargetCostLabel                 matlab.ui.control.Label
        PhaseImbalanceObjective         matlab.ui.control.NumericEditField
        AmplitudeImbalanceObjective     matlab.ui.control.NumericEditField
        PhaseImbalanceDropDown          matlab.ui.control.DropDown
        PhaseImbalanceDropDownLabel     matlab.ui.control.Label
        AmplitudeImbalanceDropDown      matlab.ui.control.DropDown
        AmplitudeImbalanceDropDownLabel  matlab.ui.control.Label
        CenterFreqDriftDropDown         matlab.ui.control.DropDown
        CenterFreqDriftDropDownLabel    matlab.ui.control.Label
        S41Objective                    matlab.ui.control.NumericEditField
        S31Objective                    matlab.ui.control.NumericEditField
        S41DropDown                     matlab.ui.control.DropDown
        S41DropDownLabel                matlab.ui.control.Label
        S31DropDown                     matlab.ui.control.DropDown
        S31DropDownLabel                matlab.ui.control.Label
        S21DropDown                     matlab.ui.control.DropDown
        S21DropDownLabel                matlab.ui.control.Label
        ReturnLossDropDown              matlab.ui.control.DropDown
        ReturnLossDropDownLabel         matlab.ui.control.Label
        S21Weight                       matlab.ui.control.NumericEditField
        PhaseImbalanceWeight            matlab.ui.control.NumericEditField
        AmplitudeImbalanceWeight        matlab.ui.control.NumericEditField
        S41Weight                       matlab.ui.control.NumericEditField
        ReturnLossWeight                matlab.ui.control.NumericEditField
        CenterFreqDriftWeight           matlab.ui.control.NumericEditField
        TargetCostEditField             matlab.ui.control.NumericEditField
        S31Weight                       matlab.ui.control.NumericEditField
        ReturnLossObjective             matlab.ui.control.NumericEditField
        CenterFreqDriftObjective        matlab.ui.control.NumericEditField
        S21Objective                    matlab.ui.control.NumericEditField
        GridLayout18                    matlab.ui.container.GridLayout
        TimeLimitEditField              matlab.ui.control.NumericEditField
        TimeLimitEditFieldLabel         matlab.ui.control.Label
        FreqCenterGHzEditField          matlab.ui.control.NumericEditField
        FreqCenterGHzEditFieldLabel     matlab.ui.control.Label
        StartingStepSizeEditField       matlab.ui.control.NumericEditField
        StartingStepSizeEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        MaxActuatorHeightumEditField    matlab.ui.control.NumericEditField
        MaxActuatorHeightumEditFieldLabel  matlab.ui.control.Label
        FreqMaxGHzEditField             matlab.ui.control.NumericEditField
        FreqMaxGHzEditFieldLabel        matlab.ui.control.Label
        FreqMinGHzEditField             matlab.ui.control.NumericEditField
        FreqMinGHzEditFieldLabel        matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        StopAlgorithmButton             matlab.ui.control.Button
        AlgorithmicGradDescentButton_2  matlab.ui.control.Button
        showCalculatedCost              matlab.ui.control.Label
        CalculatedCostLabel             matlab.ui.control.Label
        AlgorithmicGradDescentButton    matlab.ui.control.Button
        EtaValueEditField               matlab.ui.control.NumericEditField
        EtaValueEditFieldLabel          matlab.ui.control.Label
        VariableEtaCheckBox             matlab.ui.control.CheckBox
        LiveCheckBox                    matlab.ui.control.CheckBox
        BayesianOptimizationButton_2    matlab.ui.control.Button
        BasicSweep                      matlab.ui.control.Button
        TimeElapsedsLabel               matlab.ui.control.Label
        OpTimer                         matlab.ui.control.Label
        TwoDimSweep                     matlab.ui.control.Button
        GeneratePlotsTab                matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        GetGradPlotsButton              matlab.ui.control.Button
        OutputMetricsButton             matlab.ui.control.Button
        InitalDCCPosButton              matlab.ui.control.Button
        UIAxes5                         matlab.ui.control.UIAxes
        SmithChartTab                   matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        GetSmithChartButton             matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
    end

    %  -----------------------------------------------------------------------
    %  ARES_Micro.mlapp
    % 
    %  Application to Control Pico 2
    % 
    %  Author: Peter Skrdla and Joe Kritenbrink @ ARES Lab
    %  Purdue Univeristy (2026)
    % 
    %  The following assumptions are made:
    %    -The raspberry pi is connected via serial USB with the code
    %     uploaded
    %    -The VNA is connected via internet of ethernet
    % -----------------------------------------------------------------------

    
    properties (Access = private)
        LiveView = [];              % VNALiveView, [] until live plotting starts
        costHistory;
        gapHistory;
        minCGap;
        maxCGap;
    end
    
    properties (Access = public)
        pico;                           % Microcontroller object
        PROMPT = "EXECUTE:";            % Prompt sent from Pico for new input
        getData = "logged_Data.txt";    % Text file holding the logged data

        picoIsSetup = false;            % Pico Setup Status
        isCal = false;                  % Pico calibration status

        desiredFCenter                  % The wanted/estimated center frequency
        fmin;                           % Declares the minimum frequency
        fmax;                           % Declares the maximum frequency

        maxActuatorHeight               % The actuator ceiling or point with minimum capacitive effect
        useSurrogateModel = true;       % Determines if Surrogate Model should be used
        numActuators;                   % Number of actuators in use
        useVariableEta = false;         % Set use of variable eta in gradient descent
        startingEta;                    % Starting eta value for gradient descent
        startingStepSize = 1;           % Sets the step size of each iteration
        iteratePrecision = 1;           % Determines the number of times an algorithm should iterate
        getGaps;                        % The array of gap values currently in use
        slider;                         % Turns slider object on/off depending on status of pico connection
        editGaps;                       % Turns input object on/off depending on status of pico connection
            
        
        % Cmap: Map of Commands sent to the Pico
 
        % C00: EXIT                
        % C01: Calibrate         
        % C02: Recall Last Log   
        % C03: Specify all (four) actuator gaps
        % C04: Specify one actuator gap   
        % C05: Get the Pico Log  
       
        % For Example, Enter: C03 gap_val gap_val gap_val gap_val
        % For Example, Enter: C04 actuator# gap_val

        COMMANDS = ["C00", "C01", "C02", "C03", "C04", "C05"]; % Available inputs
        VNA = [];                                              % VNAInstCtrl driver, [] when disconnected

        % Weight Variables
        % Obj 1 & 2: Min insertion loss allowed (dB)
        % Obj 3: Max drift from center freq (Hz)
        % Obj 4: Threshold for return loss (dB)
        % Obj 5: Max Amplitude Imbalance
        % Obj 6: Exact phase difference (degrees)
        % Obj 7: Isolated Port loss (dB)
        % S21InsertionLoss;
        % S31InsertionLoss;
        % freqDrift;
        % returnLoss;
        % MagDiff;
        % PhaseDiff;
        % IsolatedLoss;

        % GRAD Weights Used: weights = [0.2, 0.2,  0.4, 0, 0.2, 0];
        targetCost;                 %Target Cost Goal (Minimum)

        % weights= zeros(7, 1);       %Weights for each objective
        % objectives = zeros(7, 1);   %Parameter Objective values
        userTData;
    end
    
    methods (Access = private)
        
        function automatedTuningSetup(app)
                app.targetCost = app.TargetCostEditField.Value;
                app.numActuators = app.NumberofActuatorsEditField.Value;
                app.iteratePrecision = app.NumberofIterationsEditField.Value;
                app.startingStepSize = app.StartingStepSizeEditField.Value;
                app.maxActuatorHeight = app.MaxActuatorHeightumEditField.Value;

                app.fmin = app.FreqMinGHzEditField.Value * 1e9;
                app.fmax = app.FreqMaxGHzEditField.Value * 1e9;
                app.desiredFCenter = app.FreqCenterGHzEditField.Value * 1e9;

                % For Gradient Descent
                app.useSurrogateModel = app.LiveCheckBox.Value;
                app.targetCost = app.TargetCostEditField.Value;
                app.startingEta = app.EtaValueEditField.Value;
                app.useVariableEta = app.VariableEtaCheckBox.Value;
                
                %Define a table of conditions, objectives, and weights
                paramNames = {'ReturnLoss'; 'S21'; 'S31'; 'S41'; 'FreqDrift'; 'AmplitudeImbalance'; 'PhaseImbalance'};

                % 1. Collect dropdown values into a cell array using {...}, then convert to string column
                conditionary = string({ ...
                    app.ReturnLossDropDown.Value; ...
                    app.S21DropDown.Value; ...
                    app.S31DropDown.Value; ...
                    app.S41DropDown.Value; ...
                    app.CenterFreqDriftDropDown.Value; ...
                    app.AmplitudeImbalanceDropDown.Value; ...
                    app.PhaseImbalanceDropDown.Value ...
                });
                
                % 2. Ensure numeric values are numeric scalars (or use string convert if needed)
                ConObjective = [ ...
                    app.ReturnLossObjective.Value; ...
                    app.S21Objective.Value; ...
                    app.S31Objective.Value; ...
                    app.S41Objective.Value; ...
                    app.CenterFreqDriftObjective.Value; ...
                    app.AmplitudeImbalanceObjective.Value; ...
                    app.PhaseImbalanceObjective.Value ...
                ];
                
                ConWeight = [ ...
                    app.ReturnLossWeight.Value; ...
                    app.S21Weight.Value; ...
                    app.S31Weight.Value; ...
                    app.S41Weight.Value; ...
                    app.CenterFreqDriftWeight.Value; ...
                    app.AmplitudeImbalanceWeight.Value; ...
                    app.PhaseImbalanceWeight.Value ...
                ];
                
                % 3. Create table
                app.userTData = table(conditionary, ConObjective, ConWeight, ...
                    'VariableNames', {'Condition', 'Objective', 'Weight'}, ...
                    'RowNames', paramNames);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add paths for application support functions.
            appDir = fileparts(mfilename('fullpath'));
            addpath(genpath(fullfile(appDir, 'support')));

            try
                app.VNASliderDropDown.Items = vnaAddressList();
            catch ME
                app.VNAStatusLabel.Text = 'Status: no instrument CSV';
            end

            app.slider = gobjects(1, 8); 
            app.editGaps = gobjects(1, 8);
    
            % Safely populate, disable, and route callbacks
            for i = 1:8
                if i == 1
                    slidName = 'Slider';
                    entName = 'EditGap';
                    
                else
                    slidName = sprintf('Slider_%d', i);
                    entName = sprintf('EditGap_%d', i);
                end
                
                app.slider(i) = app.(slidName);
                app.slider(i).Enable = 'off';
                app.slider(i).ValueChangedFcn = @(src, event) app.SliderValueChanged(event);
                
                app.editGaps(i) = app.(entName);
                app.editGaps(i).Enable = 'off';
                app.editGaps(i).ValueChangedFcn = @(src, event) app.EditGapValueChanged(event);
            end
        end

        % Button pushed function: ConnecttoPicoButton
        function ConnecttoPicoButtonPushed(app, event)
            try
                % Communication Setup
                PORT = "COM3";
                BAUD = 15000;
                
                if ~isempty(serialportfind)
                    delete(serialportfind);
                end
        
                app.pico = serialport(PORT, BAUD, "Tag", "Pico");
                configureTerminator(app.pico, "CR/LF");
                flush(app.pico);
                
                %Re-execute the main.py on the pico
                if app.picoIsSetup
                    easyWakeUp(app);
        
                else
                    % Must execute every time Pico is not setup (I.e. the
                    % pico object does not exist)
                    hardWakeUp(app); 
                    easyWakeUp(app);
                    app.picoIsSetup = true;
                end
            
                % Clear any previous commands from the Pico
                flush(app.pico)
                app.ConnectionStatusActual.Text = 'Connected';

                waitForResponse(app);
                app.ConnectionStatusActual.Text = 'Ready';

            catch exception
                app.ConnectionStatusActual.Text = 'Connection Failed';
                uialert(app.UIFigure, exception.message, 'Error Occurred');
            end

        end

        % Button pushed function: DisconnectPicoButton
        function DisconnectPicoButtonPushed(app, event)
            if app.picoIsSetup
                waitForResponse(app);
                writeline(app.pico, app.COMMANDS(1));
                app.ConnectionStatusActual.Text = 'Disconnected';
                delete(app.pico);
                clearvars;
                app.picoIsSetup = false;
            end
        end

        % Value changed function: NumberofActuatorsEditField
        function NumberofActuatorsEditFieldValueChanged(app, event)
            if (app.picoIsSetup && ~isempty(app.VNA))
            %Update Gaps
            value = app.NumberofActuatorsEditField.Value;
            app.getGaps = zeros(value, 1);
            
            % Loop through ALL 8 sliders 
                for i = 1:numel(app.slider)
                    if i <= value
                        app.slider(i).Enable = 'on';  % Enable the ones requested
                        app.editGaps(i).Enable = 'on';
                    else
                        app.slider(i).Enable = 'off'; % Grey out the rest
                        app.editGaps(i).Enable = 'off';
                    end
                end
           else
                app.ConnectionStatusActual.Text = 'Connection Failed';
                uialert(app.UIFigure, 'Hardware or VNA connection is missing.', 'Error Occurred');
            end
        end

        % Value changed function: Slider, Slider_2, Slider_3, Slider_4, 
        % ...and 4 other components
        function SliderValueChanged(app, event)
            if app.picoIsSetup
                
                srcSlider = event.Source;
                localIdx = find(app.slider == srcSlider, 1);
                value = floor(srcSlider.Value);
                
                if ~isempty(localIdx) && localIdx <= numel(app.getGaps)
                    app.getGaps(localIdx) = value;
                    
                    % Cross-link UI to paired Edit Box
                    app.editGaps(localIdx).Value = value;
           
                    % Update Actuator via Serial using the safe local index
                    commandIndividualGap(app, localIdx, value);
                end
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            if app.picoIsSetup
                commandCalibrate(app);
                app.isCal = true;
            end
        end

        % Button pushed function: SoftDisconnectButton
        function SoftDisconnectButtonPushed(app, event)
            %Ends the current running program
            if app.picoIsSetup
                commandExit(app);
                app.ConnectionStatusActual.Text = 'Soft Disconnect';
            end
        end

        % Button pushed function: LogGapsButton
        function LogGapsButtonPushed(app, event)
            if app.picoIsSetup
                commandLog(app);
            end
        end

        % Button pushed function: RecallValueButton
        function RecallValueButtonPushed(app, event)
            commandRecall(app);
        end

        % Value changed function: EditGap, EditGap_2, EditGap_3, 
        % ...and 5 other components
        function EditGapValueChanged(app, event)
           if app.picoIsSetup

                srcEnt = event.Source;
                localIdx = find(app.editGaps == srcEnt, 1);
                value = floor(srcEnt.Value);
                
                if ~isempty(localIdx) && localIdx <= numel(app.getGaps)
                    app.getGaps(localIdx) = value;
                    
                    % Cross-link UI to paired Slider
                    app.slider(localIdx).Value = value;

                    % Update Actuator via Serial using the local index
                    commandIndividualGap(app, localIdx, value)
                end
            end
        end

        % Button pushed function: GetLogButton
        function GetLogButtonPushed(app, event)
            if app.picoIsSetup
                % Clear old data out of the text area and show status
                app.LogDataTextArea.Value = "Requesting log file from Pico flash...";
                drawnow; 
                
                commandReadLog(app);
                
                % Swap the handshake token to catch the file transfer header
                oldPrompt = app.PROMPT;
                app.PROMPT = "---START_FILE_TRANSFER---";
                waitForResponse(app);
                
                % Reset prompt back to standard execution tracking
                app.PROMPT = oldPrompt;
                
                % Use a vertical string vector to prevent dimension mismatch errors
                logLines = "--- Log Retrieved: " + string(datetime('now')) + " ---" ;
                
                % Stream data lines dynamically until stop token is reached
                while true
                    if app.pico.NumBytesAvailable > 0
                        line = string(readline(app.pico));
            
                        % Stop token detection
                        if contains(line, "---END_FILE_TRANSFER---")
                            logLines = [logLines; "--- File Transfer Complete! ---"]; % Append vertically
                            break;
                        end
            
                        % Strip carriage returns and append clean string vertically
                        cleanLine = strtrim(line);
                        logLines = [logLines; cleanLine]; %#ok<AGROW>
                        
                        % Update screen live so the reader sees the file stream
                        app.LogDataTextArea.Value = logLines;
                        drawnow;
                    else
                        pause(0.005); 
                    end
                end
                
                % Final complete UI text paint
                app.LogDataTextArea.Value = logLines;
            end
        end

        % Button pushed function: ConnectVNA
        function VNAButtonPushed(app, event)
            try
                app.VNAStatusLabel.Text = 'Status: Connecting...'; drawnow;
                app.VNA = connectVNA(app.VNASliderDropDown.Value);
                idParts = split(app.VNA.IDN, ",");
                app.VNAStatusLabel.Text = "Status: " + strjoin(idParts(1:min(2,end)), " ");
            catch ME
                app.VNAStatusLabel.Text = 'Status: Failed';
                uialert(app.UIFigure, ME.message, 'VNA Connection Error');
            end
        end

        % Value changed function: LiveViewButton
        function LiveViewPressed(app, event)
            try
                if event.Value                        % toggled ON
                    if isempty(app.LiveView)
                        app.LiveView = VNALiveView(app.VNA, app.UIAxes, 'Period', 0);
                    end
                    app.LiveView.start();
                else                                  % toggled OFF
                    app.LiveView.stop();
                end
            catch ME
                app.LiveViewButton.Value = false;
                uialert(app.UIFigure, ME.message, 'VNA Live View Error');
            end
        end

        % Button pushed function: DisconnectVNA
        function DisconnectVNAButton(app, event)
            disconnectVNA(app.VNA, app.LiveView);
            app.VNA = [];
            app.LiveView = [];
            app.VNAStatusLabel.Text = 'Status: Disconnected';
            if isprop(app, 'LiveViewButton'), app.LiveViewButton.Value = false; end
        end

        % Button pushed function: TwoDimSweep
        function TwoDimSweepPushed(app, event)
            if (app.picoIsSetup && ~isempty(app.VNA) && app.NumberofActuatorsEditField.Value > 0)
                
                if ~app.isCal
                    commandCalibrate(app);
                    app.isCal = true;
                else
                    waitForResponse(app); % Only call once if already calibrated
                end
                
                % Set Variables
                gap_max = findMaxGap();
                peakCost = inf; %Note: Gaps 1 and 2 correlate to S21 and gaps 3 and 4 correlate to S31;
                automatedTuningSetup(app);

                % Run the optimization loop
                tic;
                
                for i = 1:app.iteratePrecision
                    % Run the algorithm pass
                    [currGaps, currCost] = TwoDSweep(app, gap_max);
                    
                    % Check for improvement and update parameters
                    if mean(currCost) > mean(peakCost)
                        peakCost = currDb; 
                        app.getGaps = currGaps;
                    end
                    
                    % Coarse-to-Fine Step Reduction (Mutating the local copy ensures repeatable runs)
                    if app.startingStepSize >= 2
                        app.startingStepSize = max(1, floor(app.startingStepSize / 2));
                    else
                        break; % Stop searching if we are already at maximum resolution
                    end
                end

                app.OpTimer.Text = num2str(toc);
                app.showCalculatedCost = num2str(peakCost);
                commandAllGaps(app, app.getGaps);
                if all(app.getGaps >= app.Slider.Limits(1) & app.getGaps <= app.Slider.Limits(2)) && all(mod(app.getGaps,1) == 0)
                    UpdateTuneTab(app);
                else
                    app.getGaps = 4000 .* ones(app.numActuators,1);
                    uialert(app.UIFigure, "Gap values are not suitable", 'warning'); 
                end
                
            else
                app.ConnectionStatusActual.Text = 'Connection Failed';
                uialert(app.UIFigure, 'Hardware, VNA connection is missing or Num Actuators is not set in Tune Tab.', 'Error Occurred');            
            end
        end

        % Button pushed function: BasicSweep
        function BasicSweepPushed(app, event)
            if app.picoIsSetup && ~isempty(app.VNA) && app.NumberofActuatorsEditField.Value > 0

                if ~app.isCal
                    commandCalibrate(app);
                    app.isCal = true;
                else
                    waitForResponse(app); % Only call once if already calibrated
                end

                % Set Variables
                gap_max = findMaxGap();
                peakCost = inf(app.numActuators, 1);

                automatedTuningSetup(app);

                % Run the optimization loop
                tic;
                for i = 1:app.iteratePrecision
        
                    % Run the optimization pass
                    [currGaps, currCost] = SweepBasic(app, gap_max);
                    
                    % Check for improvement and update parameters
                    if mean(currCost) > mean(peakCost)
                        peakCost = currCost; 
                        app.getGaps = currGaps;
                    end
                    
                    % Coarse-to-Fine Step Reduction (Mutating the local copy ensures repeatable runs)
                    if app.startingStepSize >= 2
                        app.startingStepSize = max(1, floor(app.startingStepSize / 2));
                    else
                        break; % Stop searching if we are already at maximum resolution
                    end
                end
                
                app.OpTimer.Text = num2str(toc);
                app.showCalculatedCost = num2str(peakCost);
                if all(app.getGaps >= app.Slider.Limits(1) & app.getGaps <= app.Slider.Limits(2)) && all(mod(app.getGaps,1) == 0)
                    UpdateTuneTab(app);
                else
                    app.getGaps = 4000 .* ones(app.numActuators,1);
                    uialert(app.UIFigure, "Gap values are not suitable", 'warning'); 
                end

                commandAllGaps(app, app.getGaps);
                
                % % Only attempt to update UI if gaps were successfully retrieved
                % if ~isempty(app.getGaps)
                %     app.NumberofActuatorsEditField.Value = length(app.getGaps);
                %     disp(app.getGaps)
                % 
                %     % UPDATE ALL UI ELEMENTS WITH THE NEW OPTIMIZED VALUES
                %     % (Assumes app.slider and app.editGaps arrays are constructed in startupFcn)
                %     for idx = 1:numel(app.getGaps)
                %         optimizedValue = app.getGaps(idx);
                % 
                %         % Update the Sliders array
                %         app.slider(idx).Value = optimizedValue;
                % 
                %         % Update the tracking Edit Boxes array 
                %         app.editGaps(idx).Value = optimizedValue;
                %     end
                % end
            else
                app.ConnectionStatusActual.Text = 'Connection Failed';
                uialert(app.UIFigure, 'Hardware, VNA connection is missing or Num Actuators is not set in Tune Tab.', 'Error Occurred');
            end
        end

        % Button pushed function: AlgorithmicGradDescentButton
        function AlgorithmicGradDescentButtonPushed(app, event)
            if app.picoIsSetup && ~isempty(app.VNA) && app.NumberofActuatorsEditField.Value > 0
                if ~app.isCal
                    commandCalibrate(app);
                    app.isCal = true;
                else
                    waitForResponse(app); % Only call once if already calibrated
                end

                automatedTuningSetup(app);

                tic; 
                
                [app.getGaps, app.costHistory, app.gapHistory, app.minCGap, app.maxCGap] = evaGradAlgo(app);
                
                app.OpTimer.Text = num2str(toc);
                app.showCalculatedCost = num2str(app.costHistory(end));

                if all(app.getGaps >= app.Slider.Limits(1) & app.getGaps <= app.Slider.Limits(2)) && all(mod(app.getGaps,1) == 0)
                    UpdateTuneTab(app);
                else
                    app.getGaps = 4000 .* ones(app.numActuators,1);
                    uialert(app.UIFigure, "Gap values are not suitable", 'warning'); 
                end

                commandAllGaps(app, app.getGaps);
            end
        end

        % Button pushed function: InitalDCCPosButton
        function InitalDCCPosButtonPushed(app, event)
            plotGradDescentStartPos()
        end

        % Button pushed function: OutputMetricsButton
        function OutputMetricsButtonPushed(app, event)
            plotOutputMetrics(app)
        end

        % Button pushed function: GetGradPlotsButton
        function GetGradPlotsButtonPushed(app, event)
            GradientFunctionPlots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MainTabs = uitabgroup(app.GridLayout);
            app.SetupTab = uitab(app.MainTabs);
            app.SetupTab.Title = 'Setup';
            app.GridLayout2 = uigridlayout(app.SetupTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.VNAStatusLabel = uilabel(app.GridLayout3);
            app.VNAStatusLabel.Text = 'Status:';
            app.VNALabel = uilabel(app.GridLayout3);
            app.VNALabel.Text = 'VNA';
            app.ConnecttoPicoButton = uibutton(app.GridLayout3, 'push');
            app.ConnecttoPicoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoPicoButtonPushed, true);
            app.ConnecttoPicoButton.Text = 'Connect to Pico';
            app.DisconnectPicoButton = uibutton(app.GridLayout3, 'push');
            app.DisconnectPicoButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectPicoButtonPushed, true);
            app.DisconnectPicoButton.Text = 'Disconnect Pico';
            app.ConnectionStatusLabel = uilabel(app.GridLayout3);
            app.ConnectionStatusLabel.Text = 'Status:';
            app.ConnectionStatusActual = uilabel(app.GridLayout3);
            app.ConnectionStatusActual.Text = '';
            app.SoftDisconnectButton = uibutton(app.GridLayout3, 'push');
            app.SoftDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @SoftDisconnectButtonPushed, true);
            app.SoftDisconnectButton.Text = 'Soft Disconnect';
            app.DisconnectVNA = uibutton(app.GridLayout3, 'push');
            app.DisconnectVNA.ButtonPushedFcn = createCallbackFcn(app, @DisconnectVNAButton, true);
            app.DisconnectVNA.Text = 'Disconnect VNA';
            app.VNASliderDropDown = uidropdown(app.GridLayout3);
            app.VNASliderDropDown.Items = {};
            app.VNASliderDropDown.Placeholder = 'Enter address:';
            app.VNASliderDropDown.Value = {};
            app.ConnectVNA = uibutton(app.GridLayout3, 'push');
            app.ConnectVNA.ButtonPushedFcn = createCallbackFcn(app, @VNAButtonPushed, true);
            app.ConnectVNA.Text = 'Connect VNA';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'support', 'images', 'ARES Icon.png');
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.Image2 = uiimage(app.GridLayout10);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'support', 'images', 'logo-dark.svg');
            app.Image3 = uiimage(app.GridLayout10);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'support', 'images', 'github-mark.svg');
            app.TuneTab = uitab(app.MainTabs);
            app.TuneTab.Title = 'Tune';
            app.GridLayout4 = uigridlayout(app.TuneTab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'S-Parameters')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.NumberofActuatorsEditFieldLabel = uilabel(app.GridLayout5);
            app.NumberofActuatorsEditFieldLabel.Text = 'Number of Actuators';
            app.NumberofActuatorsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NumberofActuatorsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofActuatorsEditFieldValueChanged, true);
            app.Slider = uislider(app.GridLayout5);
            app.Slider.Limits = [1 400];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.Slider_2 = uislider(app.GridLayout5);
            app.Slider_2.Limits = [1 400];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2.Value = 1;
            app.Slider_3 = uislider(app.GridLayout5);
            app.Slider_3.Limits = [1 400];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_3.Value = 1;
            app.Slider_4 = uislider(app.GridLayout5);
            app.Slider_4.Limits = [1 400];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_4.Value = 1;
            app.Slider_5 = uislider(app.GridLayout5);
            app.Slider_5.Limits = [1 400];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_5.Value = 1;
            app.Slider_6 = uislider(app.GridLayout5);
            app.Slider_6.Limits = [1 400];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_6.Value = 1;
            app.Slider_7 = uislider(app.GridLayout5);
            app.Slider_7.Limits = [1 400];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_7.Value = 1;
            app.Slider_8 = uislider(app.GridLayout5);
            app.Slider_8.Limits = [1 400];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_8.Value = 1;
            app.CalibrateButton = uibutton(app.GridLayout5, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.RecallValueButton = uibutton(app.GridLayout5, 'push');
            app.RecallValueButton.ButtonPushedFcn = createCallbackFcn(app, @RecallValueButtonPushed, true);
            app.RecallValueButton.Text = 'Recall Value';
            app.EditGap = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap.Limits = [1 4000];
            app.EditGap.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap.Value = 1;
            app.EditGap_2 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_2.Limits = [1 4000];
            app.EditGap_2.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_2.Value = 1;
            app.EditGap_3 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_3.Limits = [1 4000];
            app.EditGap_3.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_3.Value = 1;
            app.EditGap_4 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_4.Limits = [1 4000];
            app.EditGap_4.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_4.Value = 1;
            app.EditGap_5 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_5.Limits = [1 4000];
            app.EditGap_5.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_5.Value = 1;
            app.EditGap_6 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_6.Limits = [1 4000];
            app.EditGap_6.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_6.Value = 1;
            app.EditGap_7 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_7.Limits = [1 4000];
            app.EditGap_7.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_7.Value = 1;
            app.EditGap_8 = uieditfield(app.GridLayout5, 'numeric');
            app.EditGap_8.Limits = [1 4000];
            app.EditGap_8.ValueChangedFcn = createCallbackFcn(app, @EditGapValueChanged, true);
            app.EditGap_8.Value = 1;
            app.LiveViewButton = uibutton(app.GridLayout5, 'state');
            app.LiveViewButton.ValueChangedFcn = createCallbackFcn(app, @LiveViewPressed, true);
            app.LiveViewButton.Text = 'Live View';
            app.LogsTab = uitab(app.MainTabs);
            app.LogsTab.Title = 'Logs';
            app.GridLayout6 = uigridlayout(app.LogsTab);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.LogGapsButton = uibutton(app.GridLayout7, 'push');
            app.LogGapsButton.ButtonPushedFcn = createCallbackFcn(app, @LogGapsButtonPushed, true);
            app.LogGapsButton.Text = 'Log Gaps';
            app.GetLogButton = uibutton(app.GridLayout7, 'push');
            app.GetLogButton.ButtonPushedFcn = createCallbackFcn(app, @GetLogButtonPushed, true);
            app.GetLogButton.Text = 'Get Log';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.LogDataLabel = uilabel(app.GridLayout8);
            app.LogDataLabel.Text = 'Log Data';
            app.LogDataTextArea = uitextarea(app.GridLayout8);
            app.OptimizeTab = uitab(app.MainTabs);
            app.OptimizeTab.Title = 'Optimize';
            app.GridLayout11 = uigridlayout(app.OptimizeTab);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.TwoDimSweep = uibutton(app.GridLayout12, 'push');
            app.TwoDimSweep.ButtonPushedFcn = createCallbackFcn(app, @TwoDimSweepPushed, true);
            app.TwoDimSweep.Text = 'Algorithmic: Peak Insertion';
            app.OpTimer = uilabel(app.GridLayout12);
            app.OpTimer.Text = '0';
            app.TimeElapsedsLabel = uilabel(app.GridLayout12);
            app.TimeElapsedsLabel.Text = 'Time Elapsed (s)';
            app.BasicSweep = uibutton(app.GridLayout12, 'push');
            app.BasicSweep.ButtonPushedFcn = createCallbackFcn(app, @BasicSweepPushed, true);
            app.BasicSweep.Text = 'Algorithmic: Basic Sweep';
            app.BayesianOptimizationButton_2 = uibutton(app.GridLayout12, 'push');
            app.BayesianOptimizationButton_2.Text = 'Bayesian Optimization';
            app.LiveCheckBox = uicheckbox(app.GridLayout12);
            app.LiveCheckBox.Text = 'Live';
            app.VariableEtaCheckBox = uicheckbox(app.GridLayout12);
            app.VariableEtaCheckBox.Text = 'Variable Eta';
            app.EtaValueEditFieldLabel = uilabel(app.GridLayout12);
            app.EtaValueEditFieldLabel.Text = 'Eta Value';
            app.EtaValueEditField = uieditfield(app.GridLayout12, 'numeric');
            app.EtaValueEditField.Limits = [0 Inf];
            app.EtaValueEditField.Value = 0.5;
            app.AlgorithmicGradDescentButton = uibutton(app.GridLayout12, 'push');
            app.AlgorithmicGradDescentButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmicGradDescentButtonPushed, true);
            app.AlgorithmicGradDescentButton.Text = 'Algorithmic: Grad Descent';
            app.CalculatedCostLabel = uilabel(app.GridLayout12);
            app.CalculatedCostLabel.Text = 'Calculated Cost';
            app.showCalculatedCost = uilabel(app.GridLayout12);
            app.showCalculatedCost.Text = 'inf';
            app.AlgorithmicGradDescentButton_2 = uibutton(app.GridLayout12, 'push');
            app.AlgorithmicGradDescentButton_2.Text = 'Algorithmic: Grad Descent';
            app.StopAlgorithmButton = uibutton(app.GridLayout12, 'push');
            app.StopAlgorithmButton.Text = 'Stop Algorithm';
            app.GridLayout17 = uigridlayout(app.GridLayout11);
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.FreqMinGHzEditFieldLabel = uilabel(app.GridLayout18);
            app.FreqMinGHzEditFieldLabel.Text = 'Freq Min (GHz)';
            app.FreqMinGHzEditField = uieditfield(app.GridLayout18, 'numeric');
            app.FreqMinGHzEditField.Limits = [0.1 20];
            app.FreqMinGHzEditField.Value = 0.1;
            app.FreqMaxGHzEditFieldLabel = uilabel(app.GridLayout18);
            app.FreqMaxGHzEditFieldLabel.Text = 'Freq Max (GHz)';
            app.FreqMaxGHzEditField = uieditfield(app.GridLayout18, 'numeric');
            app.FreqMaxGHzEditField.Limits = [0.1 20];
            app.FreqMaxGHzEditField.Value = 20;
            app.MaxActuatorHeightumEditFieldLabel = uilabel(app.GridLayout18);
            app.MaxActuatorHeightumEditFieldLabel.Text = 'Max Actuator Height (um)';
            app.MaxActuatorHeightumEditField = uieditfield(app.GridLayout18, 'numeric');
            app.MaxActuatorHeightumEditField.Limits = [1 12000];
            app.MaxActuatorHeightumEditField.Value = 4000;
            app.NumberofIterationsEditFieldLabel = uilabel(app.GridLayout18);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.GridLayout18, 'numeric');
            app.NumberofIterationsEditField.Limits = [1 10];
            app.NumberofIterationsEditField.Value = 1;
            app.StartingStepSizeEditFieldLabel = uilabel(app.GridLayout18);
            app.StartingStepSizeEditFieldLabel.Text = 'Starting Step Size';
            app.StartingStepSizeEditField = uieditfield(app.GridLayout18, 'numeric');
            app.StartingStepSizeEditField.Limits = [1 Inf];
            app.StartingStepSizeEditField.Value = 1;
            app.FreqCenterGHzEditFieldLabel = uilabel(app.GridLayout18);
            app.FreqCenterGHzEditFieldLabel.Text = 'Freq Center (GHz)';
            app.FreqCenterGHzEditField = uieditfield(app.GridLayout18, 'numeric');
            app.FreqCenterGHzEditField.Limits = [0.1 20];
            app.FreqCenterGHzEditField.Value = 1.5;
            app.TimeLimitEditFieldLabel = uilabel(app.GridLayout18);
            app.TimeLimitEditFieldLabel.Text = 'Time Limit';
            app.TimeLimitEditField = uieditfield(app.GridLayout18, 'numeric');
            app.TimeLimitEditField.Limits = [0 Inf];
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.S21Objective = uieditfield(app.GridLayout19, 'numeric');
            app.CenterFreqDriftObjective = uieditfield(app.GridLayout19, 'numeric');
            app.ReturnLossObjective = uieditfield(app.GridLayout19, 'numeric');
            app.S31Weight = uieditfield(app.GridLayout19, 'numeric');
            app.S31Weight.Limits = [0 Inf];
            app.TargetCostEditField = uieditfield(app.GridLayout19, 'numeric');
            app.TargetCostEditField.Limits = [0 Inf];
            app.TargetCostEditField.Value = 1;
            app.CenterFreqDriftWeight = uieditfield(app.GridLayout19, 'numeric');
            app.CenterFreqDriftWeight.Limits = [0 Inf];
            app.ReturnLossWeight = uieditfield(app.GridLayout19, 'numeric');
            app.ReturnLossWeight.Limits = [0 Inf];
            app.S41Weight = uieditfield(app.GridLayout19, 'numeric');
            app.S41Weight.Limits = [0 Inf];
            app.AmplitudeImbalanceWeight = uieditfield(app.GridLayout19, 'numeric');
            app.AmplitudeImbalanceWeight.Limits = [0 Inf];
            app.PhaseImbalanceWeight = uieditfield(app.GridLayout19, 'numeric');
            app.PhaseImbalanceWeight.Limits = [0 Inf];
            app.S21Weight = uieditfield(app.GridLayout19, 'numeric');
            app.S21Weight.Limits = [0 Inf];
            app.ReturnLossDropDownLabel = uilabel(app.GridLayout19);
            app.ReturnLossDropDownLabel.Text = 'Return Loss';
            app.ReturnLossDropDown = uidropdown(app.GridLayout19);
            app.ReturnLossDropDown.Items = {'=', '<', '>', 'None'};
            app.ReturnLossDropDown.Value = '=';
            app.S21DropDownLabel = uilabel(app.GridLayout19);
            app.S21DropDownLabel.Text = 'S21';
            app.S21DropDown = uidropdown(app.GridLayout19);
            app.S21DropDown.Items = {'=', '<', '>', 'None'};
            app.S21DropDown.Value = '=';
            app.S31DropDownLabel = uilabel(app.GridLayout19);
            app.S31DropDownLabel.Text = 'S31';
            app.S31DropDown = uidropdown(app.GridLayout19);
            app.S31DropDown.Items = {'=', '<', '>', 'None'};
            app.S31DropDown.Value = '=';
            app.S41DropDownLabel = uilabel(app.GridLayout19);
            app.S41DropDownLabel.Text = 'S41';
            app.S41DropDown = uidropdown(app.GridLayout19);
            app.S41DropDown.Items = {'=', '<', '>', 'None'};
            app.S41DropDown.Value = '=';
            app.S31Objective = uieditfield(app.GridLayout19, 'numeric');
            app.S41Objective = uieditfield(app.GridLayout19, 'numeric');
            app.CenterFreqDriftDropDownLabel = uilabel(app.GridLayout19);
            app.CenterFreqDriftDropDownLabel.Text = 'Center Freq. Drift';
            app.CenterFreqDriftDropDown = uidropdown(app.GridLayout19);
            app.CenterFreqDriftDropDown.Items = {'=', '<', '>', 'None'};
            app.CenterFreqDriftDropDown.Value = '=';
            app.AmplitudeImbalanceDropDownLabel = uilabel(app.GridLayout19);
            app.AmplitudeImbalanceDropDownLabel.Text = 'Amplitude Imbalance';
            app.AmplitudeImbalanceDropDown = uidropdown(app.GridLayout19);
            app.AmplitudeImbalanceDropDown.Items = {'=', '<', '>', 'None'};
            app.AmplitudeImbalanceDropDown.Value = '=';
            app.PhaseImbalanceDropDownLabel = uilabel(app.GridLayout19);
            app.PhaseImbalanceDropDownLabel.Text = 'Phase Imbalance';
            app.PhaseImbalanceDropDown = uidropdown(app.GridLayout19);
            app.PhaseImbalanceDropDown.Items = {'=', '<', '>', 'None'};
            app.PhaseImbalanceDropDown.Value = '=';
            app.AmplitudeImbalanceObjective = uieditfield(app.GridLayout19, 'numeric');
            app.PhaseImbalanceObjective = uieditfield(app.GridLayout19, 'numeric');
            app.TargetCostLabel = uilabel(app.GridLayout19);
            app.TargetCostLabel.Text = 'Target Cost';
            app.ObjectiveLabel = uilabel(app.GridLayout19);
            app.ObjectiveLabel.Text = 'Objective';
            app.WeightLabel = uilabel(app.GridLayout19);
            app.WeightLabel.Text = 'Weight';
            app.GeneratePlotsTab = uitab(app.MainTabs);
            app.GeneratePlotsTab.Title = 'Generate Plots';
            app.GridLayout21 = uigridlayout(app.GeneratePlotsTab);
            app.UIAxes5 = uiaxes(app.GridLayout21);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.GridLayout22 = uigridlayout(app.GridLayout21);
            app.InitalDCCPosButton = uibutton(app.GridLayout22, 'push');
            app.InitalDCCPosButton.ButtonPushedFcn = createCallbackFcn(app, @InitalDCCPosButtonPushed, true);
            app.InitalDCCPosButton.Text = 'Inital DCC Pos.';
            app.OutputMetricsButton = uibutton(app.GridLayout22, 'push');
            app.OutputMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @OutputMetricsButtonPushed, true);
            app.OutputMetricsButton.Text = 'Output Metrics';
            app.GetGradPlotsButton = uibutton(app.GridLayout22, 'push');
            app.GetGradPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GetGradPlotsButtonPushed, true);
            app.GetGradPlotsButton.Text = 'Get Grad. Plots';
            app.SmithChartTab = uitab(app.MainTabs);
            app.SmithChartTab.Title = 'Smith Chart';
            app.GridLayout14 = uigridlayout(app.SmithChartTab);
            app.UIAxes4 = uiaxes(app.GridLayout14);
            title(app.UIAxes4, 'Smith Chart')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.GetSmithChartButton = uibutton(app.GridLayout15, 'push');
            app.GetSmithChartButton.Text = 'Get Smith Chart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARES_Micro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end